classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        FileNameLabel           matlab.ui.control.Label
        MultiplierLabel         matlab.ui.control.Label
        SensitivitySlider       matlab.ui.control.Slider
        OutlierSTDLabel         matlab.ui.control.Label
        IdentifyOutliersButton  matlab.ui.control.Button
        LoadDataButton          matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        PressureVsTempAxes      matlab.ui.control.UIAxes
        TimeSeriesAxes          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        OutliersVisible = false; % Tracks if outliers are currently displayed
        TemperatureData = [];
        PressureData = [];
    end
    
    methods (Access = private)
        
        % Main function to handle outlier detection and plotting (no changes needed here)
        function updateOutliers(app)
            % Ensure there is data plotted on the UIAxes
            if isempty(app.TimeSeriesAxes.Children)
                % Silently return if no data, button press will show alert
                return;
            end

            % --- Step 1: Get Data from the Plot ---
            lineObject = findobj(app.TimeSeriesAxes.Children, 'Type', 'line', '-and', '-not', 'Tag', 'outlier_plot');
            if isempty(lineObject)
                return; % No data line found
            end
            x_data = lineObject(1).XData;
            y_data = lineObject(1).YData;

            % --- Step 2: Clear Previous Outlier Circles ---
            % Find any previous outlier plots by looking for their 'Tag'
            previous_outliers = findobj(app.TimeSeriesAxes, 'Tag', 'outlier_plot');
            if ~isempty(previous_outliers)
                delete(previous_outliers);
            end

            % --- Step 3: Identify Outliers using Moving Average ---
            % Get the multiplier value directly from the slider
            std_multiplier = app.SensitivitySlider.Value;

            % Update the label to show just the current numeric value
            app.MultiplierLabel.Text = sprintf('%.2f', std_multiplier);

            window_size = 20;
            moving_avg = movmean(y_data, window_size);
            residuals = y_data - moving_avg;
            residual_std = std(residuals);
            threshold = std_multiplier * residual_std;
            outlier_indices = find(abs(residuals) > threshold);

            % --- Step 4: Plot New Outliers ---
            if ~isempty(outlier_indices)
                x_outliers = x_data(outlier_indices);
                y_outliers = y_data(outlier_indices);

                hold(app.TimeSeriesAxes, 'on');
                
                % Plot the new outliers and add a 'Tag' for easy removal later
                plot(app.TimeSeriesAxes, x_outliers, y_outliers, 'ro', ...
                    'MarkerSize', 10, ...
                    'LineWidth', 2, ...
                    'DisplayName', 'Outliers', ...
                    'Tag', 'outlier_plot'); % Tag this plot
                
                hold(app.TimeSeriesAxes, 'off');
            end
            
            % Refresh the legend
            legend(app.TimeSeriesAxes, 'show');
        end

                % Function to specifically hide the outlier circles
        function hideOutliers(app)
            % Find any previous outlier plots by their 'Tag' and delete them
            previous_outliers = findobj(app.TimeSeriesAxes, 'Tag', 'outlier_plot');
            if ~isempty(previous_outliers)
                delete(previous_outliers);
            end
            % Refresh the legend to remove the 'Outliers' entry
            legend(app.TimeSeriesAxes, 'show');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Hide the sensitivity slider and its label initially
            app.SensitivitySlider.Visible = 'off';
            app.MultiplierLabel.Visible = 'off';
            app.OutlierSTDLabel.Visible = 'off';
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [fileName, filePath] = uigetfile('*.csv', 'Select a Reactor Data CSV file');
            
            if isequal(fileName, 0)
                return;
            end
            
            fullPath = fullfile(filePath, fileName);
            
            try
                data = readtable(fullPath);
                
                colNames = data.Properties.VariableNames;
                timeColIdx = find(contains(colNames, 'Time', 'IgnoreCase', true), 1);
                tempColIdx = find(contains(colNames, 'Temp', 'IgnoreCase', true), 1);
                pressureColIdx = find(contains(colNames, 'Pressure', 'IgnoreCase', true), 1);
                
                if ~isempty(timeColIdx) && ~isempty(tempColIdx) && ~isempty(pressureColIdx)
                    
                    timeData = data{:, timeColIdx};
                    app.TemperatureData = data{:, tempColIdx};
                    app.PressureData = data{:, pressureColIdx};
                    
                    % --- PLOT 1: Time Series Plot ---
                    cla(app.TimeSeriesAxes, 'reset');
                    yyaxis(app.TimeSeriesAxes, 'left');
                    plot(app.TimeSeriesAxes, timeData, app.TemperatureData, 'b-', 'LineWidth', 1.5);
                    ylabel(app.TimeSeriesAxes, 'Temperature (°C)', 'Color', 'b');
                    app.TimeSeriesAxes.YColor = 'b';
            
                    yyaxis(app.TimeSeriesAxes, 'right');
                    plot(app.TimeSeriesAxes, timeData, app.PressureData, 'r-', 'LineWidth', 1.5);
                    ylabel(app.TimeSeriesAxes, 'Pressure (Pa)', 'Color', 'r');
                    app.TimeSeriesAxes.YColor = 'r';
            
                    % --- MODIFIED: Use a static title ---
                    title(app.TimeSeriesAxes, 'Pressure & Temperature vs. Time');
                    xlabel(app.TimeSeriesAxes, 'Time (sec)');
                    legend(app.TimeSeriesAxes, 'Temperature', 'Pressure', 'Location', 'northwest');
                    grid(app.TimeSeriesAxes, 'on');

                    % --- PLOT 2: Pressure vs. Temperature Plot ---
                    cla(app.PressureVsTempAxes, 'reset');
                    plot(app.PressureVsTempAxes, app.TemperatureData, app.PressureData, 'k.', 'MarkerSize', 10);
                    title(app.PressureVsTempAxes, 'Pressure vs. Temperature');
                    xlabel(app.PressureVsTempAxes, 'Temperature (°C)');
                    ylabel(app.PressureVsTempAxes, 'Pressure (Pa)');
                    grid(app.PressureVsTempAxes, 'on');

                    % --- NEW: Update the file name label ---
                    app.FileNameLabel.Text = {'Loaded File:', strrep(fileName, '_', ' ')};
        
                else
                    missingColsMsg = sprintf('Selected file must have columns containing "Time", "Temp", and "Pressure".');
                    uialert(app.UIFigure, missingColsMsg, 'Invalid File Format');
                end
                
            catch ME
                uialert(app.UIFigure, ['Error loading file: ' ME.message], 'Error');
                % --- NEW: Update label on error ---
                app.FileNameLabel.Text = 'Loaded File: Error';
            end
        end

        % Button pushed function: IdentifyOutliersButton
        function IdentifyOutliersButtonPushed(app, event)
            app.OutliersVisible = ~app.OutliersVisible;
            if app.OutliersVisible
                app.updateOutliers();
                app.IdentifyOutliersButton.Text = 'Hide Outliers';
                app.SensitivitySlider.Visible = 'on';
                app.MultiplierLabel.Visible = 'on';
                app.OutlierSTDLabel.Visible = 'on';
            else
                app.hideOutliers();
                app.IdentifyOutliersButton.Text = 'Identify Outliers';
                app.SensitivitySlider.Visible = 'off';
                app.MultiplierLabel.Visible = 'off';
                app.OutlierSTDLabel.Visible = 'off';
            end
        end

        % Value changed function: SensitivitySlider
        function SensitivitySliderValueChanged(app, event)
            % When the slider value changes, just re-run the outlier detection
            app.updateOutliers();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.IdentifyOutliersButton = uibutton(app.LeftPanel, 'push');
            app.IdentifyOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyOutliersButtonPushed, true);
            app.IdentifyOutliersButton.Text = 'Identify Outliers';
            app.OutlierSTDLabel = uilabel(app.LeftPanel);
            app.OutlierSTDLabel.Text = 'Outlier STD:';
            app.SensitivitySlider = uislider(app.LeftPanel);
            app.SensitivitySlider.Limits = [0.5 4];
            app.SensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @SensitivitySliderValueChanged, true);
            app.SensitivitySlider.Value = 2.5;
            app.MultiplierLabel = uilabel(app.LeftPanel);
            app.MultiplierLabel.Text = '';
            app.FileNameLabel = uilabel(app.LeftPanel);
            app.FileNameLabel.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.TimeSeriesAxes = uiaxes(app.RightPanel);
            title(app.TimeSeriesAxes, 'Title')
            xlabel(app.TimeSeriesAxes, 'X')
            ylabel(app.TimeSeriesAxes, 'Y')
            zlabel(app.TimeSeriesAxes, 'Z')
            app.PressureVsTempAxes = uiaxes(app.RightPanel);
            title(app.PressureVsTempAxes, 'Title')
            xlabel(app.PressureVsTempAxes, 'X')
            ylabel(app.PressureVsTempAxes, 'Y')
            zlabel(app.PressureVsTempAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end